classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Label_2                       matlab.ui.control.Label
        PredictButton_2               matlab.ui.control.Button
        AlexNetClassifierLabel        matlab.ui.control.Label
        knnClassifierLabel            matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        PredictButton                 matlab.ui.control.Button
        BUTTERFLYCLASSIFICATIONLabel  matlab.ui.control.Label
        SelectImageButton             matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        classKnn; % Description
        pathName; % Description
    end
    
    properties (Access = private)
        %newLabel; % Description
    end
    
    methods (Access = public)
        
        
        function output = knnClassifier(app)
            %clc;
% Load input data from .mat file
            Newpath = app.pathName.value;
            load("/MATLAB Drive/traditional/feature1.mat"); % Assumes the file contains a cell array called 'data' with one cell per class
%run('featureExtraction.m');
            path = Newpath;
            img = imread(path);
            new_size = [256, 256];
            B = imresize(img, new_size);
            grayImage = im2gray(B);
            nFeature(1,8) = 0;
            distance = 1; % Distance between the pixels
            directions = [0 1; -1 1; -1 0; -1 -1]; % Directions to consider
            numGrayLevels = 256; % Number of gray levels

% Compute the GLCM
            glcm = graycomatrix(grayImage, 'Offset', directions, 'NumLevels', numGrayLevels, 'GrayLimits', []);
            contrast1 = graycoprops(glcm, 'Contrast');
            contrast2 = cell2mat(struct2cell(contrast1));
            contrast_mean = (contrast2(1)+contrast2(2)+contrast2(3)+contrast2(4))/4;
            energy1 = graycoprops(glcm, 'Energy');
            energy2 = cell2mat(struct2cell(energy1));
            energy_mean = (energy2(1)+energy2(2)+energy2(3)+energy2(4))/4;
            homogeneity1 = graycoprops(glcm, 'Homogeneity');
            homogeneity2 = cell2mat(struct2cell(homogeneity1));
            homogeneity_mean = (homogeneity2(1)+homogeneity2(2)+homogeneity2(3)+homogeneity2(4))/4;
            correlation1 = graycoprops(glcm, 'Correlation');
            correlation2 = cell2mat(struct2cell(correlation1));
            correlation_mean = (correlation2(1)+correlation2(2)+correlation2(3)+correlation2(4))/4;

            %Local Binary Pattern

            % Compute the LBP matrix
            lbp_radius = 1;
            lbp_points = 8;
            lbp_mat = extractLBPFeatures(grayImage, 'Radius', lbp_radius, 'NumNeighbors', lbp_points);
            %Considering a standard image for calculating Correlation for each
            %image in the sample
            img = imread("/MATLAB Drive/traditional/resference images.jfif");
            grayImage2 = im2gray(img);
            %Calculating LBP matrix of reference image
            lbp_mat_ref = extractLBPFeatures(grayImage2, 'Radius', lbp_radius, 'NumNeighbors', lbp_points);
            % Extract texture features from the LBP matrix of original samples

            energy = sum(lbp_mat_ref.^2)/numel(lbp_mat);
            contrast = sum(sum(bsxfun(@minus, lbp_mat, mean(lbp_mat)).^2));
            correlation = corr2(lbp_mat, lbp_mat_ref);
            homogeneity = sum(1./(1+lbp_mat.^2))/numel(lbp_mat);

            %Entering the Extracted contrast, energy, homogeneity and correlation
            %features into Feature matrix

            nFeature(1,1) = contrast_mean;
            nFeature(1,2) = energy_mean;
            nFeature(1,3) = homogeneity_mean;
            nFeature(1,4) = correlation_mean;
            %LBP features
            nFeature(1,5) = contrast;
            nFeature(1,6) = energy;
            nFeature(1,7) = correlation;
            nFeature(1,8) = homogeneity;
            load("/MATLAB Drive/traditional/knnLabels.mat");
            % Define the number of classes
            num_classes = length(Feature);

            n_arr = {'BLUE MORMON','BUCKEYE','CABBAGE WHITE','COMMON ROSE','CRIMSON PATCHED LONGWING','MONARCH','REDADMIRAL','BLACK SWALLOWTAIL'};
            % Define the number of neighbors
            k = 3;

            test_features = nFeature;

            % Concatenate the features into a single row vector
            test_feature_vector = test_features(:)';

            % Compute distances between the test feature vector and each class's training data
            dists = zeros(num_classes, size(train_data{1}, 1));
            for i = 1:num_classes
                dists(i, :) = sum(bsxfun(@minus, train_data{i}, test_feature_vector).^2, 2)';
            end


            % Find k nearest neighbors for the test feature vector
            [~, indices] = mink(dists(:), k);

            % Predict the label for the test image
            predicted_labels = zeros(k, 1);
            for i = 1:k
                [class_index, sample_index] = ind2sub([num_classes, size(train_data{1}, 1)], indices(i));
                predicted_labels(i) = class_index;
            end
            label = mode(predicted_labels);
            %disp(['Predicted label: ' num2str(label)]);
            accuracy = sum(predicted_labels(:) == label) / numel(predicted_labels)*100;
            output = char(n_arr(label));
            app.classKnn = output;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            % Open file selection dialog
            [filename, pathname] = uigetfile({'*.jpg', 'Image Files'}, 'Select an image file');

            % Check if a file was selected
            if isequal(filename, 0)
                return; % No file selected, do nothing
            end

            % Load the selected image
            image = imread(fullfile(pathname, filename));
            imagePath = fullfile(pathname, filename);
            app.pathName.value = imagePath;
            disp(app.pathName);
            % Display the image on the axes
            axes(app.UIAxes); 
            imshow(image, 'Parent', app.UIAxes);
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            % Call the external MATLAB program
            disp(app.pathName);
            final_path = app.pathName;
            res = app.knnClassifier();
            app.Label.Text = res;
        end

        % Button pushed function: PredictButton_2
        function PredictButton_2Pushed(app, event)
            final_path = app.pathName.value;
            % Load the trained model
            loaded = load('/MATLAB Drive/DeepLearning/modeltrain.mat');
            net = loaded.net;

            % Use the trained network to classify a single test image
            img = imread(final_path);
            img = imresize(img, [227, 227]);
            predictedLabel = classify(net, img);
            app.Label_2.Text = predictedLabel;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'IMAGE')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.BUTTERFLYCLASSIFICATIONLabel = uilabel(app.UIFigure);
            app.BUTTERFLYCLASSIFICATIONLabel.Text = 'BUTTERFLY CLASSIFICATION';
            app.PredictButton = uibutton(app.UIFigure, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.knnClassifierLabel = uilabel(app.UIFigure);
            app.knnClassifierLabel.Text = 'knn Classifier';
            app.AlexNetClassifierLabel = uilabel(app.UIFigure);
            app.AlexNetClassifierLabel.Text = 'AlexNet Classifier';
            app.PredictButton_2 = uibutton(app.UIFigure, 'push');
            app.PredictButton_2.ButtonPushedFcn = createCallbackFcn(app, @PredictButton_2Pushed, true);
            app.PredictButton_2.Text = 'Predict';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end